classdef VR_App_Params < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VRExperimentsParamsUIFigure     matlab.ui.Figure
        AmplitudeKnobLabel              matlab.ui.control.Label
        AmplitudeKnob                   matlab.ui.control.DiscreteKnob
        VelocityKnobLabel               matlab.ui.control.Label
        VelocityKnob                    matlab.ui.control.DiscreteKnob
        GUIIRFSwitchLabel               matlab.ui.control.Label
        GUIIRFSwitch                    matlab.ui.control.Switch
        TrialNumberDropDownLabel        matlab.ui.control.Label
        TrialNumberDropDown             matlab.ui.control.DropDown
        DataDetrendSwitchLabel          matlab.ui.control.Label
        DataDetrendSwitch               matlab.ui.control.Switch
        VelocityasInputSwitchLabel      matlab.ui.control.Label
        VelocityasInputSwitch           matlab.ui.control.Switch
        ContinueButton                  matlab.ui.control.Button
        ExitButton                      matlab.ui.control.Button
        VelocityasOutputSwitchLabel     matlab.ui.control.Label
        VelocityasOutputSwitch          matlab.ui.control.Switch
        VRExperimentsParametersLabel    matlab.ui.control.Label
        OptionsLabel                    matlab.ui.control.Label
        Lamp_1                          matlab.ui.control.Lamp
        Lamp_2                          matlab.ui.control.Lamp
        Lamp_3                          matlab.ui.control.Lamp
        Lamp_4                          matlab.ui.control.Lamp
        Label_1                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        DecimationRatedrEditFieldLabel  matlab.ui.control.Label
        DecimationRatedrEditField       matlab.ui.control.NumericEditField
        IRFlagssEditFieldLabel          matlab.ui.control.Label
        IRFlagssEditField               matlab.ui.control.NumericEditField
        FRnFFTEditFieldLabel            matlab.ui.control.Label
        FRnFFTEditField                 matlab.ui.control.NumericEditField
        SamplingRateSREditFieldLabel    matlab.ui.control.Label
        SamplingRateSREditField         matlab.ui.control.NumericEditField
        SRLabel                         matlab.ui.control.Label
        SRdrLabel                       matlab.ui.control.Label
        IRFSidesSwitchLabel             matlab.ui.control.Label
        IRFSidesSwitch                  matlab.ui.control.Switch
        nSidesLabel                     matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        CloseAllFiguresButton           matlab.ui.control.Button
    end

    
    properties (Access = public)
        Subject_data;
        SubjIndex;
        
        bDDT_VR;
        bDDT_Output;
        bDetrend;
        bGui_IRF;
        TrialNumber;
        Amplitude;
        Velocity;
        nLags;
        nFFT;
        nSides;
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Subject_data, SubjIndex)
            app.Subject_data = Subject_data;
            app.SubjIndex = SubjIndex;
            app.Label_3.Text = num2str(app.IRFSidesSwitch.Value);
            value = app.DataDetrendSwitch.Value;
            if (value=='1')
                app.Lamp_2.Color = 'g';
            else
                app.Lamp_2.Color = 'r';
            end           
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            app.delete
            close all
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            app.Amplitude = str2num(app.AmplitudeKnob.Value);
            app.Velocity = str2num(app.VelocityKnob.Value);
            app.TrialNumber = str2num(app.TrialNumberDropDown.Value);
            app.bDDT_VR = str2num(app.VelocityasInputSwitch.Value);
            app.bDDT_Output = str2num(app.VelocityasOutputSwitch.Value);
            app.bGui_IRF = str2num(app.GUIIRFSwitch.Value);
            app.bDetrend = str2num(app.DataDetrendSwitch.Value);
            app.nLags = app.IRFlagssEditField.Value * (app.SamplingRateSREditField.Value/app.DecimationRatedrEditField.Value);
            app.nFFT = app.FRnFFTEditField.Value * app.SamplingRateSREditField.Value;
            app.nSides = str2num(app.IRFSidesSwitch.Value);
            VR_Analysis_Versatile_App(app.SubjIndex, app.Subject_data, app.Amplitude, app.Velocity, app.TrialNumber, app.bDetrend, app.bDDT_VR, app.bDDT_Output, app.bGui_IRF, ...
                app.SamplingRateSREditField.Value, app.DecimationRatedrEditField.Value, app.nFFT, app.nLags, app.nSides)
        end

        % Value changed function: GUIIRFSwitch
        function GUIIRFSwitchValueChanged(app, event)
            value = app.GUIIRFSwitch.Value;
            if (value=='1')
                app.Lamp_1.Color = 'g';
            else
                app.Lamp_1.Color = 'r';
            end
        end

        % Value changed function: DataDetrendSwitch
        function DataDetrendSwitchValueChanged(app, event)
            value = app.DataDetrendSwitch.Value;
            if (value=='1')
                app.Lamp_2.Color = 'g';
            else
                app.Lamp_2.Color = 'r';
            end
        end

        % Value changed function: VelocityasInputSwitch
        function VelocityasInputSwitchValueChanged(app, event)
            value = app.VelocityasInputSwitch.Value;
            if (value=='1')
                app.Lamp_3.Color = 'g';
            else
                app.Lamp_3.Color = 'r';
            end
        end

        % Value changed function: VelocityasOutputSwitch
        function VelocityasOutputSwitchValueChanged(app, event)
            value = app.VelocityasOutputSwitch.Value;
            if (value=='1')
                app.Lamp_4.Color = 'g';
            else
                app.Lamp_4.Color = 'r';
            end
        end

        % Value changed function: AmplitudeKnob
        function AmplitudeKnobValueChanged(app, event)
            value = app.AmplitudeKnob.Value;
            app.Label_1.Text = value;
            
        end

        % Value changed function: VelocityKnob
        function VelocityKnobValueChanged(app, event)
            value = app.VelocityKnob.Value;
            app.Label_2.Text = value;
        end

        % Close request function: VRExperimentsParamsUIFigure
        function VRExperimentsParamsUIFigureCloseRequest(app, event)
            delete(app)
            app.delete
            close all
        end

        % Value changed function: IRFSidesSwitch
        function IRFSidesSwitchValueChanged(app, event)
            value = app.IRFSidesSwitch.Value;
            app.Label_3.Text = num2str(value);
        end

        % Button pushed function: CloseAllFiguresButton
        function CloseAllFiguresButtonPushed(app, event)
            close all
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VRExperimentsParamsUIFigure = uifigure;
            app.VRExperimentsParamsUIFigure.Name = 'VR Experiments Params';
            app.VRExperimentsParamsUIFigure.CloseRequestFcn = createCallbackFcn(app, @VRExperimentsParamsUIFigureCloseRequest, true);
            app.AmplitudeKnobLabel = uilabel(app.VRExperimentsParamsUIFigure);
            app.AmplitudeKnobLabel.Text = 'Amplitude';
            app.AmplitudeKnob = uiknob(app.VRExperimentsParamsUIFigure, 'discrete');
            app.AmplitudeKnob.Items = {'1 deg', '2 deg', '5 deg', '10 deg'};
            app.AmplitudeKnob.ItemsData = {'1', '2', '5', '10'};
            app.AmplitudeKnob.ValueChangedFcn = createCallbackFcn(app, @AmplitudeKnobValueChanged, true);
            app.AmplitudeKnob.Value = '2';
            app.VelocityKnobLabel = uilabel(app.VRExperimentsParamsUIFigure);
            app.VelocityKnobLabel.Text = 'Velocity';
            app.VelocityKnob = uiknob(app.VRExperimentsParamsUIFigure, 'discrete');
            app.VelocityKnob.Items = {'2 dps', '5 dps', '10 dps'};
            app.VelocityKnob.ItemsData = {'2', ' 5', ' 10'};
            app.VelocityKnob.ValueChangedFcn = createCallbackFcn(app, @VelocityKnobValueChanged, true);
            app.VelocityKnob.Value = ' 5';
            app.GUIIRFSwitchLabel = uilabel(app.VRExperimentsParamsUIFigure);
            app.GUIIRFSwitchLabel.Text = 'GUI IRF';
            app.GUIIRFSwitch = uiswitch(app.VRExperimentsParamsUIFigure, 'slider');
            app.GUIIRFSwitch.ItemsData = {'0', '1'};
            app.GUIIRFSwitch.ValueChangedFcn = createCallbackFcn(app, @GUIIRFSwitchValueChanged, true);
            app.GUIIRFSwitch.Value = '0';
            app.TrialNumberDropDownLabel = uilabel(app.VRExperimentsParamsUIFigure);
            app.TrialNumberDropDownLabel.Text = 'Trial Number';
            app.TrialNumberDropDown = uidropdown(app.VRExperimentsParamsUIFigure);
            app.TrialNumberDropDown.Items = {'Trial 1', 'Trial 2', 'Trial 3'};
            app.TrialNumberDropDown.ItemsData = {'1', '2', '3'};
            app.TrialNumberDropDown.Value = '1';
            app.DataDetrendSwitchLabel = uilabel(app.VRExperimentsParamsUIFigure);
            app.DataDetrendSwitchLabel.Text = 'Data Detrend';
            app.DataDetrendSwitch = uiswitch(app.VRExperimentsParamsUIFigure, 'slider');
            app.DataDetrendSwitch.ItemsData = {'0', '1'};
            app.DataDetrendSwitch.ValueChangedFcn = createCallbackFcn(app, @DataDetrendSwitchValueChanged, true);
            app.DataDetrendSwitch.Value = '1';
            app.VelocityasInputSwitchLabel = uilabel(app.VRExperimentsParamsUIFigure);
            app.VelocityasInputSwitchLabel.Text = 'Velocity as Input';
            app.VelocityasInputSwitch = uiswitch(app.VRExperimentsParamsUIFigure, 'slider');
            app.VelocityasInputSwitch.ItemsData = {'0', '1'};
            app.VelocityasInputSwitch.ValueChangedFcn = createCallbackFcn(app, @VelocityasInputSwitchValueChanged, true);
            app.VelocityasInputSwitch.Value = '0';
            app.ContinueButton = uibutton(app.VRExperimentsParamsUIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.ExitButton = uibutton(app.VRExperimentsParamsUIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.VelocityasOutputSwitchLabel = uilabel(app.VRExperimentsParamsUIFigure);
            app.VelocityasOutputSwitchLabel.Text = 'Velocity as Output';
            app.VelocityasOutputSwitch = uiswitch(app.VRExperimentsParamsUIFigure, 'slider');
            app.VelocityasOutputSwitch.ItemsData = {'0', '1'};
            app.VelocityasOutputSwitch.ValueChangedFcn = createCallbackFcn(app, @VelocityasOutputSwitchValueChanged, true);
            app.VelocityasOutputSwitch.Value = '0';
            app.VRExperimentsParametersLabel = uilabel(app.VRExperimentsParamsUIFigure);
            app.VRExperimentsParametersLabel.Text = 'VR Experiments Parameters';
            app.OptionsLabel = uilabel(app.VRExperimentsParamsUIFigure);
            app.OptionsLabel.Text = 'Options:';
            app.Lamp_1 = uilamp(app.VRExperimentsParamsUIFigure);
            app.Lamp_2 = uilamp(app.VRExperimentsParamsUIFigure);
            app.Lamp_3 = uilamp(app.VRExperimentsParamsUIFigure);
            app.Lamp_4 = uilamp(app.VRExperimentsParamsUIFigure);
            app.Label_1 = uilabel(app.VRExperimentsParamsUIFigure);
            app.Label_1.Text = '';
            app.Label_2 = uilabel(app.VRExperimentsParamsUIFigure);
            app.Label_2.Text = '';
            app.DecimationRatedrEditFieldLabel = uilabel(app.VRExperimentsParamsUIFigure);
            app.DecimationRatedrEditFieldLabel.Text = 'Decimation Rate (dr)';
            app.DecimationRatedrEditField = uieditfield(app.VRExperimentsParamsUIFigure, 'numeric');
            app.DecimationRatedrEditField.Value = 100;
            app.IRFlagssEditFieldLabel = uilabel(app.VRExperimentsParamsUIFigure);
            app.IRFlagssEditFieldLabel.Text = 'IRF lags (s)';
            app.IRFlagssEditField = uieditfield(app.VRExperimentsParamsUIFigure, 'numeric');
            app.IRFlagssEditField.Value = 10;
            app.FRnFFTEditFieldLabel = uilabel(app.VRExperimentsParamsUIFigure);
            app.FRnFFTEditFieldLabel.Text = 'FR nFFT';
            app.FRnFFTEditField = uieditfield(app.VRExperimentsParamsUIFigure, 'numeric');
            app.FRnFFTEditField.Value = 25;
            app.SamplingRateSREditFieldLabel = uilabel(app.VRExperimentsParamsUIFigure);
            app.SamplingRateSREditFieldLabel.Text = 'Sampling Rate (SR)';
            app.SamplingRateSREditField = uieditfield(app.VRExperimentsParamsUIFigure, 'numeric');
            app.SamplingRateSREditField.Value = 1000;
            app.SRLabel = uilabel(app.VRExperimentsParamsUIFigure);
            app.SRLabel.Text = '* SR';
            app.SRdrLabel = uilabel(app.VRExperimentsParamsUIFigure);
            app.SRdrLabel.Text = '*(SR/dr)';
            app.IRFSidesSwitchLabel = uilabel(app.VRExperimentsParamsUIFigure);
            app.IRFSidesSwitchLabel.Text = 'IRF Sides';
            app.IRFSidesSwitch = uiswitch(app.VRExperimentsParamsUIFigure, 'slider');
            app.IRFSidesSwitch.Items = {'1', '2'};
            app.IRFSidesSwitch.ItemsData = {'1', '2'};
            app.IRFSidesSwitch.ValueChangedFcn = createCallbackFcn(app, @IRFSidesSwitchValueChanged, true);
            app.IRFSidesSwitch.Value = '2';
            app.nSidesLabel = uilabel(app.VRExperimentsParamsUIFigure);
            app.nSidesLabel.Text = 'nSides = ';
            app.Label_3 = uilabel(app.VRExperimentsParamsUIFigure);
            app.Label_3.Text = '';
            app.CloseAllFiguresButton = uibutton(app.VRExperimentsParamsUIFigure, 'push');
            app.CloseAllFiguresButton.ButtonPushedFcn = createCallbackFcn(app, @CloseAllFiguresButtonPushed, true);
            app.CloseAllFiguresButton.Text = 'Close All Figures';
        end
    end

    methods (Access = public)

        % Construct app
        function app = VR_App_Params(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VRExperimentsParamsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VRExperimentsParamsUIFigure)
        end
    end
end