classdef VR_App_main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VRAnalysisMainUIFigure  matlab.ui.Figure
        PleaseselectthesubjectListBoxLabel  matlab.ui.control.Label
        SubjectListBox          matlab.ui.control.ListBox
        OKButton                matlab.ui.control.StateButton
        ExitButton              matlab.ui.control.StateButton
        Label                   matlab.ui.control.Label
        VRStanceExperimentsAnalysisLabel  matlab.ui.control.Label
        AbolfazlMohebbiPouyaAmiriRobertKearneyLabel  matlab.ui.control.Label
        SelectedSubjectLabel    matlab.ui.control.Label
        SubjectIndexLabel       matlab.ui.control.Label
        Label_2                 matlab.ui.control.Label
    end

    
    properties (Access = public)
        Subject_data;
        SubjIndex;
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            dataPath = pwd + "\data\";
            fid = fopen('SubjectsParams/SubjectsParams_2.csv');
            app.Subject_data = textscan(fid, '%d %s %f %f %f %f %f %f %d %d %s %s %d', 'Delimiter', ',', 'HeaderLines', 1);
            fclose(fid);
            Subject_Names = app.Subject_data{2};
            Exp_date = app.Subject_data{12};
            str_full = '';
            for i=1:length(Subject_Names)
                str_buff = strcat(int2str(i),'- ', string(Subject_Names(i)), ' -- ', string(Exp_date(i)));
                str_full = [str_full, str_buff];
            end
            app.SubjectListBox.Items = str_full(2:end);            
            
            %             app.SubjectListBox.Items{1} = Subject_Names{1};
            %             SubjIndex = input(strcat('Which subject would you like to examine?\n',str_full) );
        end

        % Value changed function: ExitButton
        function ExitButtonPushed(app, event)
            app.delete
        end

        % Value changed function: SubjectListBox
        function SubjectListBoxValueChanged(app, event)
            value = app.SubjectListBox.Value;
            app.Label.Text = value;
            [~,app.SubjIndex] = ismember(app.SubjectListBox.Value, app.SubjectListBox.Items);
            app.Label_2.Text = int2str(app.SubjIndex);
        end

        % Value changed function: OKButton
        function OKButtonPushed(app, event)
            %VR_Analysis_Versatile_App(app.SubjIndex, app.Subject_data)
            VR_App_Params(app.Subject_data, app.SubjIndex)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VRAnalysisMainUIFigure = uifigure;
            app.VRAnalysisMainUIFigure.Name = 'VR Analysis Main';
            app.PleaseselectthesubjectListBoxLabel = uilabel(app.VRAnalysisMainUIFigure);
            app.PleaseselectthesubjectListBoxLabel.Text = 'Please select the subject:';
            app.SubjectListBox = uilistbox(app.VRAnalysisMainUIFigure);
            app.SubjectListBox.Items = {};
            app.SubjectListBox.ValueChangedFcn = createCallbackFcn(app, @SubjectListBoxValueChanged, true);
            app.SubjectListBox.Value = {};
            app.OKButton = uibutton(app.VRAnalysisMainUIFigure, 'state');
            app.OKButton.ValueChangedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.ExitButton = uibutton(app.VRAnalysisMainUIFigure, 'state');
            app.ExitButton.ValueChangedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.Label = uilabel(app.VRAnalysisMainUIFigure);
            app.VRStanceExperimentsAnalysisLabel = uilabel(app.VRAnalysisMainUIFigure);
            app.VRStanceExperimentsAnalysisLabel.Text = 'VR Stance Experiments Analysis';
            app.AbolfazlMohebbiPouyaAmiriRobertKearneyLabel = uilabel(app.VRAnalysisMainUIFigure);
            app.AbolfazlMohebbiPouyaAmiriRobertKearneyLabel.Text = 'Abolfazl Mohebbi, Pouya Amiri, Robert Kearney';
            app.SelectedSubjectLabel = uilabel(app.VRAnalysisMainUIFigure);
            app.SelectedSubjectLabel.Text = 'Selected Subject:';
            app.SubjectIndexLabel = uilabel(app.VRAnalysisMainUIFigure);
            app.SubjectIndexLabel.Text = 'Subject Index:';
            app.Label_2 = uilabel(app.VRAnalysisMainUIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = VR_App_main(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VRAnalysisMainUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VRAnalysisMainUIFigure)
        end
    end
end